classdef Tugas1_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        NewButton                   matlab.ui.control.Button
        UploadButton                matlab.ui.control.Button
        ImageEnhancementToolsLabel  matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        AfterLabel                  matlab.ui.control.Label
        BeforeLabel                 matlab.ui.control.Label
        MatchingButton              matlab.ui.control.Button
        EqualizatonButton           matlab.ui.control.Button
        RGBpictureCheckBox          matlab.ui.control.CheckBox
        ContrastButton              matlab.ui.control.Button
        GrayAxes_2                  matlab.ui.control.UIAxes
        GrayAxes                    matlab.ui.control.UIAxes
        GreenAxes_2                 matlab.ui.control.UIAxes
        MatchImageAxes              matlab.ui.control.UIAxes
        AfterImageAxes              matlab.ui.control.UIAxes
        BeforeImageAxes             matlab.ui.control.UIAxes
        GreenAxes                   matlab.ui.control.UIAxes
        BlueAxes_2                  matlab.ui.control.UIAxes
        RedAxes_2                   matlab.ui.control.UIAxes
        BlueAxes                    matlab.ui.control.UIAxes
        RedAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        oriImage % Original image, meant to be processed
    end
    
    methods (Access = private)
        % === NO 1: SHOW HISTOGRAM
        function counts = graylevelHistogram(app, grayImage)
            [rows, columns, rgb] = size(grayImage);
            counts = zeros(1, 256);
            for col = 1 : columns
	            for row = 1 : rows
		            % Get the gray level.
		            grayLevel = grayImage(row, col);
		            % Add 1 because graylevel zero goes into index 1 and so on.
		            counts(grayLevel+ 1) = counts(grayLevel+1) + 1;
                end 
            end
        end

        function [countR, countG, countB] = rgbHistogram(app, rgbImage)
            [rows, columns, rgb] = size(rgbImage);
            countR = zeros(1, 256);
            countG = zeros(1, 256);
            countB = zeros(1, 256);
            for col = 1 : columns
	            for row = 1 : rows
		            % Get the gray level.
		            redLevel = rgbImage(row, col, 1);
                    greenLevel = rgbImage(row, col, 2);
                    blueLevel = rgbImage(row, col, 3);
		            % Add 1 because graylevel zero goes into index 1 and so on.
		            countR(redLevel+ 1) = countR(redLevel+1) + 1;
                    countG(greenLevel+ 1) = countG(greenLevel+1) + 1;
                    countB(blueLevel+ 1) = countB(blueLevel+1) + 1;
                end 
            end
        end
        
        function grayShow(app, counts, num)
            % Plot the histogram.
            grayLevels = 0 : 255;
            if (num == 1)
                % load histogram
                app.GrayAxes.Visible = 'on';
                bar(app.GrayAxes, grayLevels, counts, 'BarWidth', 1, 'FaceColor', 'flat');
            elseif (num == 2)
                % load histogram
                app.GrayAxes_2.Visible = 'on';
                bar(app.GrayAxes_2, grayLevels, counts, 'BarWidth', 1, 'FaceColor', 'flat');
            end
        end

        function rgbShow(app, countR, countG, countB, num)
            % Plot the histogram.
            redLevels = 0 : 255;
            greenLevels = 0 : 255;
            blueLevels = 0 : 255;
            if (num == 1)
                % load histogram
                app.RedAxes.Visible = 'on';
                bar(app.RedAxes, redLevels, countR, 'BarWidth', 1, 'FaceColor', 'r');

                app.BlueAxes.Visible = 'on';
                bar(app.BlueAxes, greenLevels, countG, 'BarWidth', 1, 'FaceColor', 'g');

                app.GreenAxes.Visible = 'on';
                bar(app.GreenAxes, blueLevels, countB, 'BarWidth', 1, 'FaceColor', 'b');
            elseif (num == 2)
                % load histogram
                app.RedAxes_2.Visible = 'on';
                bar(app.RedAxes_2, redLevels, countR, 'BarWidth', 1, 'FaceColor', 'r');

                app.BlueAxes_2.Visible = 'on';
                bar(app.BlueAxes_2, greenLevels, countG, 'BarWidth', 1, 'FaceColor', 'g');

                app.GreenAxes_2.Visible = 'on';
                bar(app.GreenAxes_2, blueLevels, countB, 'BarWidth', 1, 'FaceColor', 'b');
            end
        end
        % === NO 2: CONTRAST ENHANCEMENT
        function new_image = enhanceContrastBw(app, image)
            min_val = min(image(:)); % the minimum grayscale value
            max_val = max(image(:)); % the maximum grayscale value

            new_image = (image - min_val).*(max_val/(max_val-min_val));
        end

        function new_image = enhanceContrastRgb(app,image)
            new_image(:,:,1) = enhanceContrastBw(app,image(:,:,1));
            new_image(:,:,2) = enhanceContrastBw(app,image(:,:,2));
            new_image(:,:,3) = enhanceContrastBw(app,image(:,:,3));
        end

        % === NO 3: HISTOGRAM EQUALIZATION
        function newImage = hisEqualization(app, image)
            [rows ,cols ,rgb]=size(image);
            newImage = image;
            histEq = zeros(256);
            histFreq = zeros(256);
            for i = 1:rows
                for j = 1:cols
                    histFreq(image(i,j)+1)=histFreq(image(i,j)+1)+1;
                end
            end
            sum = 0.0;
            for i = 1:256
                sum = sum + histFreq(i);
                histEq(i) = floor((sum/(rows*cols))*255);
            end
            for i = 1:rows
                for j = 1:cols
                newImage(i,j) = histEq(image(i,j)+1);
                end
            end
        end

        % === NO 4: HISTOGRAM MATCHING
        function newImage = histSpecification(app, im1, im2)
            newImage = im1;
            temp1 = equalization(app, im1);
            im1Eq = floor(temp1 * 255);

            temp2 = equalization(app, im2);
            im2Eq = floor(temp2 * 255);
    
            invHist = zeros(1, 256);
            for i = 1:256
                minIdx = 1;
                minVal = abs(im1Eq(i) - im2Eq(1));
                for j = 2:256
                    if ((abs(im1Eq(i) - im2Eq(j))) < minVal)
                        minVal = abs(im1Eq(i) - im2Eq(j));
                        minIdx = j - 1;
                    end
                end
                invHist(i) = minIdx;
            end

            [row, col, rgb] = size(im1);
            for i = 1:row
                for j = 1:col
                    for k = 1:rgb
                        newImage(i, j, k) = invHist(im1(i, j, k)+1);
                    end
                end
            end
        end

        function res = equalization(app, im)
            [row, col, rgb] = size(im);
            histFreq = zeros(1, 256);

            for i = 1:row
                for j = 1:col
                    for k = 1:rgb
                        histFreq(im(i, j, k) + 1) = histFreq(im(i, j, k) + 1) + 1;
                    end
                end
            end

            res = zeros(1, 256);
            sum = 0;
            for i = 1:256
                sum = sum + histFreq(i);
                res(i) = sum;
            end

            res=res/(row * col * rgb);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.BeforeImageAxes.Colormap = gray(256);
            axis(app.BeforeImageAxes, 'image');

            app.MatchImageAxes.Visible = 'off';
            app.MatchImageAxes.Colormap = gray(256);
            axis(app.MatchImageAxes, 'image');
            
            app.AfterImageAxes.Colormap = gray(256);
            axis(app.AfterImageAxes, 'image');

            % Turn off all histogram axes visibility
            app.GrayAxes.Visible = 'off';
            app.GrayAxes_2.Visible = 'off';

            app.RedAxes.Visible = 'off';
            app.GreenAxes.Visible = 'off';
            app.BlueAxes.Visible = 'off';

            app.RedAxes_2.Visible = 'off';
            app.GreenAxes_2.Visible = 'off';
            app.BlueAxes_2.Visible = 'off';

            app.NewButton.Enable = 'off';
        end

        % Callback function
        function DropDownValueChanged(app, event)

        end

        % Button pushed function: ContrastButton
        function ContrastButtonPushed(app, event)
            if (app.RGBpictureCheckBox.Value)
            % image is RGB
                new_image = enhanceContrastRgb(app,app.oriImage);
                imagesc(app.AfterImageAxes,new_image);
                [countR, countG, countB] = rgbHistogram(app, new_image);
                rgbShow(app, countR, countG, countB, 2);
            else
            % image is grayscale
                new_image = enhanceContrastBw(app,app.oriImage);
                imagesc(app.AfterImageAxes,new_image);
                counts = graylevelHistogram(app, new_image);
                grayShow(app, counts, 2);
            end
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            [filename,filepath] = uigetfile({'*.jpg;*.tif;*.png;*.gif'}, 'Select File to Open');
            if ischar(filepath)
                fullname = [filepath, filename];
            end
            ImageFile = imread(fullname);
            imagesc(app.BeforeImageAxes,ImageFile);
            app.oriImage = ImageFile;

            app.UploadButton.Enable = 'off';
            app.RGBpictureCheckBox.Enable = 'off';
            app.NewButton.Enable = 'on';

            % Show histogram
            if (app.RGBpictureCheckBox.Value)
            % image is RGB
                app.RedAxes.Visible = 'on';
                app.GreenAxes.Visible = 'on';
                app.BlueAxes.Visible = 'on';

                % load histogram
                [countR, countG, countB] =rgbHistogram(app, ImageFile);
                rgbShow(app, countR, countG, countB, 1);

            else 
            % image is grayscale
                app.GrayAxes.Visible = 'on';

                % load histogram
                counts = graylevelHistogram(app,ImageFile);
                grayShow(app, counts, 1);
            end
        end

        % Button pushed function: MatchingButton
        function MatchingButtonPushed(app, event)
            % upload photo
            [filename,filepath] = uigetfile({'*.jpg;*.tif;*.png;*.gif'}, 'Select File to Open');
            if ischar(filepath)
                fullname = [filepath, filename];
            end
            ImageFile = imread(fullname);
            imagesc(app.MatchImageAxes,ImageFile);

            % extract histogram
            if (app.RGBpictureCheckBox.Value)
            % image is RGB
                newImage = histSpecification(app, app.oriImage, ImageFile);
                imagesc(app.AfterImageAxes,newImage);
                [countR, countG, countB] = rgbHistogram(app, newImage);
                rgbShow(app, countR, countG, countB, 2);
            else
            % image is grayscale
                newImage = histSpecification(app, app.oriImage, ImageFile);
                imagesc(app.AfterImageAxes,newImage);
                counts = graylevelHistogram(app, newImage);
                grayShow(app, counts, 2);
            end  
        end

        % Callback function
        function RGBpictureCheckBoxValueChanged(app, event)
            
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            cla(app.BeforeImageAxes);
            cla(app.AfterImageAxes);
            cla(app.MatchImageAxes);
            app.MatchImageAxes.Visible = 'off';

            cla(app.GrayAxes);
            app.GrayAxes.Visible = 'off';
            cla(app.GrayAxes_2);
            app.GrayAxes_2.Visible = 'off';
            cla(app.RedAxes);
            app.RedAxes.Visible = 'off';
            cla(app.RedAxes_2);
            app.RedAxes_2.Visible = 'off';
            cla(app.GreenAxes);
            app.GreenAxes.Visible = 'off';
            cla(app.GreenAxes_2);
            app.GreenAxes_2.Visible = 'off';
            cla(app.BlueAxes);
            app.BlueAxes.Visible = 'off';
            cla(app.BlueAxes_2);
            app.BlueAxes_2.Visible = 'off';

            app.NewButton.Enable = 'off';
            app.RGBpictureCheckBox.Enable = 'on';
            app.UploadButton.Enable = 'on';
        end

        % Button pushed function: EqualizatonButton
        function EqualizatonButtonPushed(app, event)
            if (app.RGBpictureCheckBox.Value)
            % image is RGB
                new_image(:,:,1) = hisEqualization(app, app.oriImage(:,:,1));
                new_image(:,:,2) = hisEqualization(app, app.oriImage(:,:,2));
                new_image(:,:,3) = hisEqualization(app, app.oriImage(:,:,3));
                imagesc(app.AfterImageAxes,new_image);
                [countR, countG, countB] = rgbHistogram(app, new_image);
                rgbShow(app, countR, countG, countB, 2);
            else
            % image is grayscale
                new_image = hisEqualization(app, app.oriImage);
                imagesc(app.AfterImageAxes,new_image);
                counts = graylevelHistogram(app, new_image);
                grayShow(app, counts, 2);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.RedAxes = uiaxes(app.UIFigure);
            title(app.RedAxes, 'Red')
            xlabel(app.RedAxes, 'Gray Level')
            ylabel(app.RedAxes, 'Pixels')
            app.RedAxes.XLim = [0 255];
            app.RedAxes.XTick = [0 128 255];
            app.BlueAxes = uiaxes(app.UIFigure);
            title(app.BlueAxes, 'Blue')
            xlabel(app.BlueAxes, 'Gray Level')
            ylabel(app.BlueAxes, 'Pixels')
            app.BlueAxes.XLim = [0 255];
            app.BlueAxes.XTick = [0 128 255];
            app.RedAxes_2 = uiaxes(app.UIFigure);
            title(app.RedAxes_2, 'Red')
            xlabel(app.RedAxes_2, 'Gray Level')
            ylabel(app.RedAxes_2, 'Pixels')
            app.RedAxes_2.XLim = [0 255];
            app.RedAxes_2.XTick = [0 128 255];
            app.BlueAxes_2 = uiaxes(app.UIFigure);
            title(app.BlueAxes_2, 'Blue')
            xlabel(app.BlueAxes_2, 'Gray Level')
            ylabel(app.BlueAxes_2, 'Pixels')
            app.BlueAxes_2.XLim = [0 255];
            app.BlueAxes_2.XTick = [0 128 255];
            app.GreenAxes = uiaxes(app.UIFigure);
            title(app.GreenAxes, 'Green')
            xlabel(app.GreenAxes, 'Gray Level')
            ylabel(app.GreenAxes, 'Pixels')
            app.GreenAxes.XLim = [0 255];
            app.GreenAxes.XTick = [0 128 255];
            app.BeforeImageAxes = uiaxes(app.UIFigure);
            app.BeforeImageAxes.XTick = [];
            app.BeforeImageAxes.XTickLabel = {'[ ]'};
            app.BeforeImageAxes.YTick = [];
            app.AfterImageAxes = uiaxes(app.UIFigure);
            app.AfterImageAxes.XTick = [];
            app.AfterImageAxes.XTickLabel = {'[ ]'};
            app.AfterImageAxes.YTick = [];
            app.MatchImageAxes = uiaxes(app.UIFigure);
            app.MatchImageAxes.XTick = [];
            app.MatchImageAxes.XTickLabel = {'[ ]'};
            app.MatchImageAxes.YTick = [];
            app.GreenAxes_2 = uiaxes(app.UIFigure);
            title(app.GreenAxes_2, 'Green')
            xlabel(app.GreenAxes_2, 'Gray Level')
            ylabel(app.GreenAxes_2, 'Pixels')
            app.GreenAxes_2.XLim = [0 255];
            app.GreenAxes_2.XTick = [0 128 255];
            app.GrayAxes = uiaxes(app.UIFigure);
            title(app.GrayAxes, 'Grayscale')
            xlabel(app.GrayAxes, 'Gray Level')
            ylabel(app.GrayAxes, 'Pixels')
            app.GrayAxes.XLim = [0 255];
            app.GrayAxes.XTick = [0 128 255];
            app.GrayAxes.Visible = 'off';
            app.GrayAxes_2 = uiaxes(app.UIFigure);
            title(app.GrayAxes_2, 'Grayscale')
            xlabel(app.GrayAxes_2, 'Gray Level')
            ylabel(app.GrayAxes_2, 'Pixels')
            app.GrayAxes_2.XLim = [0 255];
            app.GrayAxes_2.XTick = [0 128 255];
            app.GrayAxes_2.Visible = 'off';
            app.ContrastButton = uibutton(app.UIFigure, 'push');
            app.ContrastButton.ButtonPushedFcn = createCallbackFcn(app, @ContrastButtonPushed, true);
            app.ContrastButton.Text = 'Contrast enhancement';
            app.RGBpictureCheckBox = uicheckbox(app.UIFigure);
            app.RGBpictureCheckBox.Text = 'RGB picture';
            app.EqualizatonButton = uibutton(app.UIFigure, 'push');
            app.EqualizatonButton.ButtonPushedFcn = createCallbackFcn(app, @EqualizatonButtonPushed, true);
            app.EqualizatonButton.Text = 'Histogram equalization';
            app.MatchingButton = uibutton(app.UIFigure, 'push');
            app.MatchingButton.ButtonPushedFcn = createCallbackFcn(app, @MatchingButtonPushed, true);
            app.MatchingButton.Text = 'Histogram matching';
            app.BeforeLabel = uilabel(app.UIFigure);
            app.BeforeLabel.Text = 'Before';
            app.AfterLabel = uilabel(app.UIFigure);
            app.AfterLabel.Text = 'After';
            app.Label = uilabel(app.UIFigure);
            app.ImageEnhancementToolsLabel = uilabel(app.UIFigure);
            app.ImageEnhancementToolsLabel.Text = {'Image Enhancement'; 'Tools'};
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload image';
            app.NewButton = uibutton(app.UIFigure, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tugas1_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end